classdef IBIS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FIBISUIFigure                  matlab.ui.Figure
        ClearGroupButton               matlab.ui.control.Button
        ClearAllButton                 matlab.ui.control.Button
        GroupButton                    matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
        ImListBox                      matlab.ui.control.ListBox
        TabGroup                       matlab.ui.container.TabGroup
        ImageTab                       matlab.ui.container.Tab
        CopyButton                     matlab.ui.control.Button
        SelectImageButtonGroup         matlab.ui.container.ButtonGroup
        FinalButton                    matlab.ui.control.RadioButton
        LabeledButton                  matlab.ui.control.RadioButton
        OriginalButton                 matlab.ui.control.RadioButton
        FinalImage                     matlab.ui.control.UIAxes
        LabeledImage                   matlab.ui.control.UIAxes
        OriginalImage                  matlab.ui.control.UIAxes
        ParameterTab                   matlab.ui.container.Tab
        RunSimilarityTestButton        matlab.ui.control.Button
        SimilarityResultTextArea       matlab.ui.control.TextArea
        SimilarityResultTextAreaLabel  matlab.ui.control.Label
        FrequencySpinner               matlab.ui.control.Spinner
        FrequencySpinnerLabel          matlab.ui.control.Label
        CurrentimageEditField          matlab.ui.control.EditField
        CurrentimageEditFieldLabel     matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        ProcessButton                  matlab.ui.control.Button
        DenoisefrequencypeakLabel      matlab.ui.control.Label
        GaussianfilterLabel            matlab.ui.control.Label
        MitochondriasizemLabel         matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        SliderLabel                    matlab.ui.control.Label
        MinlengthEditField             matlab.ui.control.NumericEditField
        MinlengthEditFieldLabel        matlab.ui.control.Label
        MaxlengthEditField             matlab.ui.control.NumericEditField
        MaxlengthEditFieldLabel        matlab.ui.control.Label
        GaussiansigmaSpinner           matlab.ui.control.Spinner
        GaussiansigmaSpinnerLabel      matlab.ui.control.Label
        ParFinalImage                  matlab.ui.control.UIAxes
        DenoisedImage                  matlab.ui.control.UIAxes
        PhasorTab                      matlab.ui.container.Tab
        Point2EditField                matlab.ui.control.EditField
        Point2EditFieldLabel           matlab.ui.control.Label
        Point1EditField                matlab.ui.control.EditField
        Point1EditFieldLabel           matlab.ui.control.Label
        Setpoint2Button                matlab.ui.control.Button
        ClearPhasorsButton             matlab.ui.control.Button
        LinearfreeboundfractionLabel   matlab.ui.control.Label
        Setpoint1Button                matlab.ui.control.Button
        GroupOptionsDropDown           matlab.ui.control.DropDown
        GroupOptionsDropDownLabel      matlab.ui.control.Label
        PlotphasorsButton              matlab.ui.control.Button
        PhasorUI                       matlab.ui.control.UIAxes
        AnalysisTab                    matlab.ui.container.Tab
        SavetoTextButton               matlab.ui.control.Button
        ShowonGraphButton              matlab.ui.control.Button
        SelectanalysisDropDown         matlab.ui.control.DropDown
        SelectanalysisDropDownLabel    matlab.ui.control.Label
        ShowFBfractionButton           matlab.ui.control.Button
        GraphPlots                     matlab.ui.control.UIAxes
        PseudoColorImage               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        FileName % Description
        tempImFiles = {} % Description
        PixelSize % Description
        listAll % Description
        selectedAll % All selected image listbox number
        selectedIm % Individual selected image listbox number
        tempPhasorFiles = struct('FileName',[],'ImageFiles',[],'Phasors',[],'groupNo',[],'FBfraction',[],'PseudoImage',[]) % Description
        groupSelected % image listbox number for grouping        
        groupList % Description
        ExhibitImNum % Description
        selectedGroup % Description
        Point1 % Description
        Point2 % Description
        GaussianSigma % Description
        MaxLength % Description
        MinLength % Description
        FreqPeak % Description
        PhasorPlotCounts = 0 % Description
        scatterPhasor = {} % Description
        tempProcessIm % Description
        currentG % Description
        currentS % Description
        Point1Set % Description
        Point2Set % Description
        currentScatter % Description
        similarityAns % Description
        analysisSelected % Description
        FBfractionTable % Description
        MitoNumTable % Description
        selectedImageButton % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            pxsize = deterPxSize();
            [FLIM,FileNames,PathName] = refread();
            %initialize null array for images and phasors
            ImgArray = {}; phasors = {};
           
                
                for i = 1:length(FileNames)
                    int=FLIM{i}(:,:,1);
                    pha=FLIM{i}(:,:,2)*3.1416/180;
                    mod=FLIM{i}(:,:,3);
                    G=mod.*cos(pha);
                    S=mod.*sin(pha);
                    
                    int = fliplr(imrotate(int,-90)); 
                    G = fliplr(imrotate(G,-90));
                    S = fliplr(imrotate(S,-90));
                    
                    NormImg = normalize(int,'range');
                    ImFreqDenoise = FreqDenoiseFun(NormImg,1000);
            
                    [t,em] = graythresh(uint8(ImFreqDenoise));
                    if em > 0.8 && round(t*max(ImFreqDenoise(:))) ~= 0
                    intensityQuantile = round(t*max(ImFreqDenoise(:)));
                    else
                    intensityQuantile = quantile(ImFreqDenoise(ImFreqDenoise~=0),0.9);
                    end
            
                    ImGaussFilteredd = gaussFilter(ImFreqDenoise,5);
                    ImMaskFilter = thresholdImage(ImGaussFilteredd,intensityQuantile);
                    ImMaskInt = ImGaussFilteredd.*ImMaskFilter;
                    ImMitoSized = deterMitoSize(pxsize,ImMaskFilter,ImGaussFilteredd,30,0.75);
            
                    [Phasor, ImLabel] = integratedG(ImMitoSized,G,S);
                    PhasorSize = size(Phasor);
                    ImExhibit = cat(5,int,ImLabel,ImMitoSized,G,S);
                    
                    ImgArray(i) = mat2cell(ImExhibit,256,256,1,1,5);
                    phasors(i) = mat2cell(Phasor,PhasorSize(1),PhasorSize(2));
                    
                    app.FileName = [app.FileName, FileNames(i)];
                end             
            
            
            %Save all files to structure and list them
%             Ind = structfun(@isempty,app.tempPhasorFiles);
            if isempty(app.ImListBox.ItemsData) == 1
                for i = 1:length(FileNames)
                app.tempPhasorFiles(i) = struct('FileName',FileNames(i),'ImageFiles',ImgArray(i),'Phasors',phasors(i),'groupNo',[],'FBfraction',[],'PseudoImage',[]);    
                end
            else
                OriginalItems = length(app.tempPhasorFiles);
                for i = 1:length(FileNames)
                app.tempPhasorFiles(i+ OriginalItems) = struct('FileName',FileNames(i),'ImageFiles',ImgArray(i),'Phasors',phasors(i),'groupNo',[],'FBfraction',[],'PseudoImage',[]);
                end
            end
                
            %Update image listbox
            app.listAll = app.FileName;
            app.ImListBox.Items = app.listAll;
            app.ImListBox.ItemsData = (1:length(app.listAll));
            
            app.ImListBox.Value = 1;
            ImListBoxValueChanged(app,event)


        end

        % Value changed function: ImListBox
        function ImListBoxValueChanged(app, event)
   
            app.selectedAll = app.ImListBox.Value;
            app.selectedIm = app.selectedAll(1);
            CurrentimageEditFieldValueChanged(app, event)
            
            ShowIm = app.tempPhasorFiles(app.selectedIm).ImageFiles;
            
            OrigIm = ShowIm(:,:,1,1,1);
            LabIm = ShowIm(:,:,1,1,2);
            FinalIm = ShowIm(:,:,1,1,3);
            
            imagesc(app.OriginalImage,OrigIm);
            imagesc(app.LabeledImage,LabIm);
            imagesc(app.FinalImage,FinalIm);
        end

        % Button pushed function: GroupButton
        function GroupButtonPushed(app, event)
            groupNameCell = groupName();            
            app.groupList = [app.groupList, groupNameCell];
            
            %Update group listbox
            app.GroupOptionsDropDown.Items = app.groupList;
            app.GroupOptionsDropDown.ItemsData = (1:length(app.groupList));
            
            app.GroupOptionsDropDown.Value = 1;
            GroupOptionsDropDownValueChanged(app, event)
            
            %assign phasor files to group
            app.groupSelected = app.selectedAll;           
            for groupItem = 1:length(app.groupSelected)                
                app.tempPhasorFiles(app.groupSelected(groupItem)).groupNo = length(app.groupList);                
            end
            
        end

        % Value changed function: GroupOptionsDropDown
        function GroupOptionsDropDownValueChanged(app, event)
            app.selectedGroup = app.GroupOptionsDropDown.Value;
            
        end

        % Callback function
        function ImageexhibitButtonGroupSelectionChanged(app, event)
            NumExhibitIm = app.ImageexhibitButtonGroup.SelectedObject;            
            app.ExhibitImNum = str2double(NumExhibitIm.Tag);
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(app, event)
            SelectedImage = app.selectedImageButton.Text;
            switch SelectedImage
                case 'Original'
                app.OriginalImage.Title.String = [];
                copygraphics(app.OriginalImage);
                app.OriginalImage.Title.String = 'Original Image';
                case 'Labeled'
                app.LabeledImage.Title.String = [];
                copygraphics(app.LabeledImage);
                app.LabeledImage.Title.String = 'Labeled Image';
                case 'Final'
                app.FinalImage.Title.String = [];
                copygraphics(app.FinalImage);
                app.FinalImage.Title.String = 'Final Image';
            end
        end

        % Button pushed function: PlotphasorsButton
        function PlotphasorsButtonPushed(app, event)
            legend(app.PhasorUI,'off');
            color = [{'r'} {'g'} {'b'} {'m'} {'c'} {'y'} {'k'}];
            DisplayName = {};
            scatterG = [];
            scatterS = [];
            
            
            app.PhasorPlotCounts = app.PhasorPlotCounts + 1;
            cNum = app.PhasorPlotCounts;
            
                    
            %Create phasor semi circle
            th = linspace( pi/2, -pi/2, 100);
            rad = 0.5;  %or whatever radius you want
            g = rad*cos(th);
            s = rad*sin(th)+0.5;
            phasorPlot = plot(app.PhasorUI,s,g,'-k','LineWidth',2); xlim(app.PhasorUI,[0 1]); ylim(app.PhasorUI,[0 0.6]);  hold(app.PhasorUI,'on');
            phasorPlot.Annotation.LegendInformation.IconDisplayStyle = 'off';
            for fileInd = 1:length(app.tempPhasorFiles)
                if app.tempPhasorFiles(fileInd).groupNo == app.selectedGroup
                    gp = app.tempPhasorFiles(fileInd).Phasors(:,1)';
                    sp = app.tempPhasorFiles(fileInd).Phasors(:,2)';
                    
                    scatterG = [scatterG gp];
                    scatterS = [scatterS sp];
                end
                
                
            end
            scatterPlot = [scatterG; scatterS];
            scatter(app.PhasorUI,scatterPlot(1,:),scatterPlot(2,:),15,char(color(cNum)),'filled','DisplayName',char(app.GroupOptionsDropDown.Items(app.selectedGroup)));
            
            
            legend(app.PhasorUI,'show');
        end

        % Button pushed function: Setpoint1Button
        function Setpoint1ButtonPushed(app, event)
           G = app.currentG; S = app.currentS;
           scatter(app.PhasorUI,G,S,72,'red');
           
           Point1EditFieldValueChanged(app, event)
            
        end

        % Value changed function: CurrentimageEditField
        function CurrentimageEditFieldValueChanged(app, event)
            
            if length(app.listAll) == 1
                displayName = app.tempPhasorFiles.FileName;
            elseif length(app.listAll) > 1
                imageInd = app.selectedIm;
                displayName = app.tempPhasorFiles(imageInd).FileName;
            end
            
            app.CurrentimageEditField.Value = char(displayName);
            
        end

        % Value changed function: GaussiansigmaSpinner
        function GaussiansigmaSpinnerValueChanged(app, event)
            app.GaussianSigma = app.GaussiansigmaSpinner.Value;
            
        end

        % Value changed function: MaxlengthEditField
        function MaxlengthEditFieldValueChanged(app, event)
            app.MaxLength = app.MaxlengthEditField.Value;
            
        end

        % Value changed function: MinlengthEditField
        function MinlengthEditFieldValueChanged(app, event)
            app.MinLength = app.MinlengthEditField.Value;
            
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.FreqPeak = event.Value;
            app.FrequencySpinner.Value = app.FreqPeak;
        end

        % Value changed function: FrequencySpinner
        function FrequencySpinnerValueChanged(app, event)
            app.FreqPeak = app.FrequencySpinner.Value;
            app.Slider.Value = app.FreqPeak;
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            if length(app.listAll) == 1
                Img = app.tempPhasorFiles.ImageFiles;
            elseif length(app.listAll) > 1
                imageInd = app.selectedIm;
                Img = app.tempPhasorFiles(imageInd).ImageFiles;
            
            end
            pxsize = deterPxSize();
            int = Img(:,:,1,1,1);
            NormImg = normalize(int,'range');
            ImFreqDenoise = FreqDenoiseFun(NormImg,app.FrequencySpinner.Value);
            
            [t,em] = graythresh(uint8(ImFreqDenoise));
             if em > 0.8 && round(t*max(ImFreqDenoise(:))) ~= 0
             intensityQuantile = round(t*max(ImFreqDenoise(:)));
             else
             intensityQuantile = quantile(ImFreqDenoise(ImFreqDenoise~=0),0.9);
             end
            
             ImGaussFilteredd = gaussFilter(ImFreqDenoise,app.GaussiansigmaSpinner.Value);
             ImMaskFilter = thresholdImage(ImGaussFilteredd,intensityQuantile);
             ImMaskInt = ImGaussFilteredd.*ImMaskFilter;
             ImMitoSized = deterMitoSize(pxsize,ImMaskFilter,ImGaussFilteredd,app.MaxlengthEditField.Value,app.MinlengthEditField.Value);
             mask = zeros(size(ImMitoSized));
             mask(ImMitoSized > 0) = 1;
             LabIm = bwlabel(mask);
             
             app.tempProcessIm = ImMitoSized;


            imagesc(app.DenoisedImage,ImFreqDenoise);
            imagesc(app.ParFinalImage,ImMitoSized);
            imagesc(app.OriginalImage,int);
            imagesc(app.LabeledImage,LabIm);
            imagesc(app.FinalImage,ImMitoSized);
             
        end

        % Button pushed function: ClearPhasorsButton
        function ClearPhasorsButtonPushed(app, event)
            cla(app.PhasorUI);
            app.PhasorPlotCounts = 0;
            legend(app.PhasorUI,'off');
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            answer = questdlg('Confirm to save current file?','Save processed image','OK','Cancel');
            
            
            switch answer
                case 'OK'
                    if length(app.listAll) == 1
                        G = app.tempPhasorFiles.ImageFiles(:,:,1,1,4); S = app.tempPhasorFiles.ImageFiles(:,:,1,1,5);
                        ImMitoSized = app.tempProcessIm;
                        app.tempPhasorFiles.ImageFiles(:,:,1,1,3) = app.tempProcessIm;
                        [Phasor, ImLabel] = integratedG(ImMitoSized,G,S);
                        app.tempPhasorFiles.Phasors = Phasor;
                    elseif length(app.listAll) > 1
                        imageInd = app.selectedIm;
                        G = app.tempPhasorFiles(imageInd).ImageFiles(:,:,1,1,4); S = app.tempPhasorFiles(imageInd).ImageFiles(:,:,1,1,5);
                        ImMitoSized = app.tempProcessIm;
                        app.tempPhasorFiles(imageInd).ImageFiles(:,:,1,1,3) = app.tempProcessIm;
                        [Phasor, ImLabel] = integratedG(ImMitoSized,G,S);
                        app.tempPhasorFiles(imageInd).Phasors = Phasor;
                    end
                case 'Cancel'
                
                    return
            end
            
        end

        % Button down function: PhasorUI
        function PhasorUIButtonDown(app, event)
            Point1 = get(app.PhasorUI,'CurrentPoint');
            app.currentG = Point1(1,1);
            app.currentS = Point1(1,2);
            app.currentScatter = scatter(app.PhasorUI,Point1(1,1),Point1(1,2),100,'black','filled','HandleVisibility','off'); hold(app.PhasorUI,'on');
            
        end

        % Value changed function: Point1EditField
        function Point1EditFieldValueChanged(app, event)
            delete(app.currentScatter);
            app.Point1Set = [app.currentG app.currentS];
            app.Point1EditField.Value = num2str(app.Point1Set);
            scatter(app.PhasorUI,app.currentG,app.currentS,100,'red','HandleVisibility','off'); hold(app.PhasorUI,'on');
        end

        % Button pushed function: Setpoint2Button
        function Setpoint2ButtonPushed(app, event)
            delete(app.currentScatter);
            app.Point2Set = [app.currentG app.currentS];
            app.Point2EditField.Value = num2str(app.Point2Set);
            scatter(app.PhasorUI,app.currentG,app.currentS,100,'red','HandleVisibility','off'); hold(app.PhasorUI,'on');    
        end

        % Button pushed function: ShowFBfractionButton
        function ShowFBfractionButtonPushed(app, event)
            load 'newGcolormap';
            if isempty(app.currentG) == 1
            Point1 = str2num(app.Point1EditField.Value);
            Point2 = str2num(app.Point2EditField.Value);
            G1 = Point1(1); S1 = Point1(2);
            G2 = Point2(1); S2 = Point2(2);
            else     
            G1 = app.Point1Set(1); S1 = app.Point1Set(2);
            G2 = app.Point2Set(1); S2 = app.Point2Set(2);
            end
            
            for ImNumber = 1:length(app.ImListBox.Items)
            Im = app.tempPhasorFiles(ImNumber).ImageFiles;
            Phasor = app.tempPhasorFiles(ImNumber).Phasors;
            PhasorFraction = FBtrajectory(Phasor,G1,S1,G2,S2); 
            FinalIm = Im(:,:,1,1,3);
            MaskedIm = zeros(size(FinalIm));
            MaskedIm(FinalIm>0) = 1;
            LabeledIm = bwlabel(MaskedIm);
            
            PseudoIm = zeros(size(LabeledIm));
            
            for MitoNum = 1:length(PhasorFraction)
                PseudoIm(LabeledIm == MitoNum) = PhasorFraction(MitoNum); 
            end
            app.tempPhasorFiles(ImNumber).FBfraction = PhasorFraction;
            app.tempPhasorFiles(ImNumber).PseudoImage = PseudoIm;
            
            end
            
            imagesc(app.PseudoColorImage,app.tempPhasorFiles(app.selectedIm).PseudoImage); colormap(app.PseudoColorImage,newColorMap); caxis(app.PseudoColorImage,[0 1]); colorbar(app.PseudoColorImage);
        end

        % Button pushed function: RunSimilarityTestButton
        function RunSimilarityTestButtonPushed(app, event)
            if length(app.listAll) == 1
                Img = app.tempPhasorFiles.ImageFiles;
            elseif length(app.listAll) > 1
                imageInd = app.selectedIm;
                Img = app.tempPhasorFiles(imageInd).ImageFiles;
            
            end
            image = Img(:,:,1,1,3);
            [peaksnr, err, ssimval, multissimval] = SimilarityTest(image);
            app.similarityAns = [peaksnr, err, ssimval, multissimval];
            
            SimilarityResultTextAreaValueChanged(app, event)
        end

        % Value changed function: SimilarityResultTextArea
        function SimilarityResultTextAreaValueChanged(app, event)
            peaksnr = app.similarityAns(1);
            mse = app.similarityAns(2);
            ssim = app.similarityAns(3);
            multi = app.similarityAns(4);
            app.SimilarityResultTextArea.Value = sprintf('Peak snr = %f3\nmse = %f3\n',peaksnr,mse,ssim,multi);
            
            
        end

        % Value changed function: SelectanalysisDropDown
        function SelectanalysisDropDownValueChanged(app, event)
            app.analysisSelected = app.SelectanalysisDropDown.Value;
            
        end

        % Button pushed function: ShowonGraphButton
        function ShowonGraphButtonPushed(app, event)
            
            switch app.SelectanalysisDropDown.Value
                case 'FB fraction'
                app.FBfractionTable = [];
                boxData = [];
                groupData = [];
                fileName = [];
                for Itemlist = 1:length(app.selectedAll)
                    groupNo = app.tempPhasorFiles(Itemlist).groupNo;
                    tempboxData = app.tempPhasorFiles(Itemlist).FBfraction';
                    tempgroupData = repmat(app.GroupOptionsDropDown.Items(groupNo),size(tempboxData));
                    tempfileName = repmat({app.tempPhasorFiles(Itemlist).FileName},size(tempboxData));
                    boxData = [boxData; tempboxData];
                    groupData = [groupData; tempgroupData];
                    fileName = [fileName; tempfileName];
                end
                app.FBfractionTable = table(fileName,groupData,boxData);
                
                yRange = 0:0.1:1;
                boxplot(app.GraphPlots,boxData,groupData); ylim(app.GraphPlots,[0 1]);
                xlabel(app.GraphPlots,'Group names'); ylabel(app.GraphPlots,'FB fraction');
                yticks(app.GraphPlots,yRange);
                
                case 'Mito number'
                boxData = [];
                groupData = [];
                fileName = {};
                for Itemlist = 1:length(app.selectedAll)
                    groupNo = app.tempPhasorFiles(Itemlist).groupNo;
                    tempboxData = length(app.tempPhasorFiles(Itemlist).Phasors);
                    tempgroupData = repmat(app.GroupOptionsDropDown.Items(groupNo),size(tempboxData));
                    boxData = [boxData; tempboxData];
                    groupData = [groupData; tempgroupData];
                    fileName(Itemlist) = cellstr(app.tempPhasorFiles(Itemlist).FileName);
                end
                fileName = fileName';
                app.MitoNumTable = table(fileName,groupData,boxData);
                yRange = min(boxData)-10:5:max(boxData)+10; 
                boxplot(app.GraphPlots,boxData,groupData); ylim(app.GraphPlots,[min(boxData)-10 max(boxData)+10]); 
                xlabel(app.GraphPlots,'Group names'); ylabel(app.GraphPlots,'Mito numbers');
                yticks(app.GraphPlots,yRange); 
%                 app.GraphPlots.Position(3)=app.GraphPlots.Position(3);
%                 app.GraphPlots.XTickMode = 'auto';
%                 xticks(app.GraphPlots,[1 30 60]); xlim(app.GraphPlots,[-50 50]);
            end
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
%             if (length(app.selectedAll) == 1)
%                 app.tempPhasorFiles(app.selectedAll) = [];
%                 app.ImListBox.Items(app.selectedAll) = [];
%                 app.ImListBox.ItemsData(app.selectedAll) = [];
%             else

%             tempListBox = app.ImListBox;
%             currentItem = get(tempListBox);
%             [~,index] = ismember(app.ImListBox.Value,app.ImListBox.Items);
%             

            deleteOrder = sort(app.selectedAll,'descend');
            for i = 1:length(deleteOrder)
                app.tempPhasorFiles(deleteOrder(i)) = [];
                app.ImListBox.Items(deleteOrder(i)) = [];
                app.ImListBox.ItemsData(deleteOrder(i)) = [];
                app.FileName(deleteOrder(i)) = [];
            end
            
%             end
            %Reorder listbox item data
            app.ImListBox.ItemsData = linspace(1,length(app.ImListBox.ItemsData),length(app.ImListBox.ItemsData));
        end

        % Button pushed function: ClearGroupButton
        function ClearGroupButtonPushed(app, event)
            for itemNumber = 1:length(app.ImListBox.Items)
                app.tempPhasorFiles(itemNumber).groupNo = [];
            end
            %Update group listbox
            deleteOrder = sort(app.GroupOptionsDropDown.ItemsData,'descend');
            for i = 1:length(deleteOrder)
                app.GroupOptionsDropDown.Items(deleteOrder(i)) = []; 
                app.GroupOptionsDropDown.ItemsData(deleteOrder(i)) = [];
            end
            app.groupList = [];
        end

        % Button pushed function: SavetoTextButton
        function SavetoTextButtonPushed(app, event)
            savePath = uigetdir(path,'Select a folder to save your files. Text files will be named as selected analysis.');
            switch app.SelectanalysisDropDown.Value
                case 'FB fraction'
                    writetable(app.FBfractionTable,strcat(savePath,'/FB fraction.txt'),'Delimiter',' ');
                case 'Mito number'
                    writetable(app.MitoNumTable,strcat(savePath,'/Mito Numbers.txt'),'Delimiter',' '); 
                    
            end
        end

        % Selection changed function: SelectImageButtonGroup
        function SelectImageButtonGroupSelectionChanged(app, event)
            app.selectedImageButton = app.SelectImageButtonGroup.SelectedObject;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FIBISUIFigure = uifigure('Visible', 'off');
            app.FIBISUIFigure.Name = 'FIBIS';
            app.TabGroup = uitabgroup(app.FIBISUIFigure);
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.OriginalImage = uiaxes(app.ImageTab);
            title(app.OriginalImage, 'Original Image')
            zlabel(app.OriginalImage, 'Z')
            app.OriginalImage.XTick = [];
            app.OriginalImage.YTick = [];
            app.LabeledImage = uiaxes(app.ImageTab);
            title(app.LabeledImage, 'Labeled Image')
            zlabel(app.LabeledImage, 'Z')
            app.LabeledImage.XTick = [];
            app.LabeledImage.YTick = [];
            app.FinalImage = uiaxes(app.ImageTab);
            title(app.FinalImage, 'Final Image')
            zlabel(app.FinalImage, 'Z')
            app.FinalImage.XTick = [];
            app.FinalImage.YTick = [];
            app.SelectImageButtonGroup = uibuttongroup(app.ImageTab);
            app.SelectImageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectImageButtonGroupSelectionChanged, true);
            app.SelectImageButtonGroup.Title = 'Select Image';
            app.OriginalButton = uiradiobutton(app.SelectImageButtonGroup);
            app.OriginalButton.Text = 'Original';
            app.OriginalButton.Value = true;
            app.LabeledButton = uiradiobutton(app.SelectImageButtonGroup);
            app.LabeledButton.Text = 'Labeled';
            app.FinalButton = uiradiobutton(app.SelectImageButtonGroup);
            app.FinalButton.Text = 'Final';
            app.CopyButton = uibutton(app.ImageTab, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Text = 'Copy Image';
            app.ParameterTab = uitab(app.TabGroup);
            app.ParameterTab.Title = 'Parameter';
            app.DenoisedImage = uiaxes(app.ParameterTab);
            title(app.DenoisedImage, 'Denoised Image')
            zlabel(app.DenoisedImage, 'Z')
            app.DenoisedImage.XTick = [];
            app.DenoisedImage.YTick = [];
            app.ParFinalImage = uiaxes(app.ParameterTab);
            title(app.ParFinalImage, 'Final Image')
            zlabel(app.ParFinalImage, 'Z')
            app.ParFinalImage.XTick = [];
            app.ParFinalImage.YTick = [];
            app.GaussiansigmaSpinnerLabel = uilabel(app.ParameterTab);
            app.GaussiansigmaSpinnerLabel.Text = 'Gaussian sigma';
            app.GaussiansigmaSpinner = uispinner(app.ParameterTab);
            app.GaussiansigmaSpinner.ValueChangedFcn = createCallbackFcn(app, @GaussiansigmaSpinnerValueChanged, true);
            app.GaussiansigmaSpinner.Value = 5;
            app.MaxlengthEditFieldLabel = uilabel(app.ParameterTab);
            app.MaxlengthEditFieldLabel.Text = 'Max length';
            app.MaxlengthEditField = uieditfield(app.ParameterTab, 'numeric');
            app.MaxlengthEditField.ValueChangedFcn = createCallbackFcn(app, @MaxlengthEditFieldValueChanged, true);
            app.MaxlengthEditField.Value = 30;
            app.MinlengthEditFieldLabel = uilabel(app.ParameterTab);
            app.MinlengthEditFieldLabel.Text = 'Min length';
            app.MinlengthEditField = uieditfield(app.ParameterTab, 'numeric');
            app.MinlengthEditField.ValueChangedFcn = createCallbackFcn(app, @MinlengthEditFieldValueChanged, true);
            app.MinlengthEditField.Value = 0.75;
            app.SliderLabel = uilabel(app.ParameterTab);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.ParameterTab);
            app.Slider.Limits = [100 2000];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 1000;
            app.MitochondriasizemLabel = uilabel(app.ParameterTab);
            app.MitochondriasizemLabel.Text = 'Mitochondria size (μm)';
            app.GaussianfilterLabel = uilabel(app.ParameterTab);
            app.GaussianfilterLabel.Text = 'Gaussian filter';
            app.DenoisefrequencypeakLabel = uilabel(app.ParameterTab);
            app.DenoisefrequencypeakLabel.Text = 'Denoise frequency peak';
            app.ProcessButton = uibutton(app.ParameterTab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.SaveButton = uibutton(app.ParameterTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CurrentimageEditFieldLabel = uilabel(app.ParameterTab);
            app.CurrentimageEditFieldLabel.Text = 'Current image';
            app.CurrentimageEditField = uieditfield(app.ParameterTab, 'text');
            app.CurrentimageEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentimageEditFieldValueChanged, true);
            app.FrequencySpinnerLabel = uilabel(app.ParameterTab);
            app.FrequencySpinnerLabel.Text = 'Frequency';
            app.FrequencySpinner = uispinner(app.ParameterTab);
            app.FrequencySpinner.Step = 100;
            app.FrequencySpinner.ValueChangedFcn = createCallbackFcn(app, @FrequencySpinnerValueChanged, true);
            app.FrequencySpinner.Value = 1000;
            app.SimilarityResultTextAreaLabel = uilabel(app.ParameterTab);
            app.SimilarityResultTextAreaLabel.Text = 'Similarity Result';
            app.SimilarityResultTextArea = uitextarea(app.ParameterTab);
            app.SimilarityResultTextArea.ValueChangedFcn = createCallbackFcn(app, @SimilarityResultTextAreaValueChanged, true);
            app.RunSimilarityTestButton = uibutton(app.ParameterTab, 'push');
            app.RunSimilarityTestButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimilarityTestButtonPushed, true);
            app.RunSimilarityTestButton.Text = 'Run Similarity Test';
            app.PhasorTab = uitab(app.TabGroup);
            app.PhasorTab.Title = 'Phasor';
            app.PhasorUI = uiaxes(app.PhasorTab);
            title(app.PhasorUI, 'Phasor plot')
            xlabel(app.PhasorUI, 'G')
            ylabel(app.PhasorUI, 'S')
            zlabel(app.PhasorUI, 'Z')
            app.PhasorUI.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.PhasorUI.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.PhasorUI.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.PhasorUI.ButtonDownFcn = createCallbackFcn(app, @PhasorUIButtonDown, true);
            app.PlotphasorsButton = uibutton(app.PhasorTab, 'push');
            app.PlotphasorsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotphasorsButtonPushed, true);
            app.PlotphasorsButton.Text = 'Plot phasors';
            app.GroupOptionsDropDownLabel = uilabel(app.PhasorTab);
            app.GroupOptionsDropDownLabel.Text = 'Group Options';
            app.GroupOptionsDropDown = uidropdown(app.PhasorTab);
            app.GroupOptionsDropDown.Items = {};
            app.GroupOptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @GroupOptionsDropDownValueChanged, true);
            app.GroupOptionsDropDown.Value = {};
            app.Setpoint1Button = uibutton(app.PhasorTab, 'push');
            app.Setpoint1Button.ButtonPushedFcn = createCallbackFcn(app, @Setpoint1ButtonPushed, true);
            app.Setpoint1Button.Text = 'Set point1';
            app.LinearfreeboundfractionLabel = uilabel(app.PhasorTab);
            app.LinearfreeboundfractionLabel.Text = 'Linear free bound fraction';
            app.ClearPhasorsButton = uibutton(app.PhasorTab, 'push');
            app.ClearPhasorsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPhasorsButtonPushed, true);
            app.ClearPhasorsButton.Text = {'Clear Phasors'; ''};
            app.Setpoint2Button = uibutton(app.PhasorTab, 'push');
            app.Setpoint2Button.ButtonPushedFcn = createCallbackFcn(app, @Setpoint2ButtonPushed, true);
            app.Setpoint2Button.Text = 'Set point2';
            app.Point1EditFieldLabel = uilabel(app.PhasorTab);
            app.Point1EditFieldLabel.Text = 'Point1';
            app.Point1EditField = uieditfield(app.PhasorTab, 'text');
            app.Point1EditField.ValueChangedFcn = createCallbackFcn(app, @Point1EditFieldValueChanged, true);
            app.Point1EditField.Value = '0.3231 0.4677';
            app.Point2EditFieldLabel = uilabel(app.PhasorTab);
            app.Point2EditFieldLabel.Text = 'Point2';
            app.Point2EditField = uieditfield(app.PhasorTab, 'text');
            app.Point2EditField.Value = '0.9611 0.1933';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.PseudoColorImage = uiaxes(app.AnalysisTab);
            title(app.PseudoColorImage, 'Pseudo Color Image')
            zlabel(app.PseudoColorImage, 'Z')
            app.PseudoColorImage.XTick = [];
            app.PseudoColorImage.YTick = [];
            app.GraphPlots = uiaxes(app.AnalysisTab);
            title(app.GraphPlots, 'Graph Plots')
            zlabel(app.GraphPlots, 'Z')
            app.GraphPlots.XTick = [];
            app.GraphPlots.YTick = [];
            app.ShowFBfractionButton = uibutton(app.AnalysisTab, 'push');
            app.ShowFBfractionButton.ButtonPushedFcn = createCallbackFcn(app, @ShowFBfractionButtonPushed, true);
            app.ShowFBfractionButton.Text = 'Show FB fraction';
            app.SelectanalysisDropDownLabel = uilabel(app.AnalysisTab);
            app.SelectanalysisDropDownLabel.Text = 'Select analysis';
            app.SelectanalysisDropDown = uidropdown(app.AnalysisTab);
            app.SelectanalysisDropDown.Items = {'FB fraction', 'Mito number'};
            app.SelectanalysisDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectanalysisDropDownValueChanged, true);
            app.SelectanalysisDropDown.Value = 'FB fraction';
            app.ShowonGraphButton = uibutton(app.AnalysisTab, 'push');
            app.ShowonGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ShowonGraphButtonPushed, true);
            app.ShowonGraphButton.Text = 'Show on Graph';
            app.SavetoTextButton = uibutton(app.AnalysisTab, 'push');
            app.SavetoTextButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoTextButtonPushed, true);
            app.SavetoTextButton.Text = 'Save to Text';
            app.ImListBox = uilistbox(app.FIBISUIFigure);
            app.ImListBox.Items = {};
            app.ImListBox.Multiselect = 'on';
            app.ImListBox.ValueChangedFcn = createCallbackFcn(app, @ImListBoxValueChanged, true);
            app.ImListBox.Value = {};
            app.LoadButton = uibutton(app.FIBISUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.GroupButton = uibutton(app.FIBISUIFigure, 'push');
            app.GroupButton.ButtonPushedFcn = createCallbackFcn(app, @GroupButtonPushed, true);
            app.GroupButton.Text = 'Group';
            app.ClearAllButton = uibutton(app.FIBISUIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.ClearGroupButton = uibutton(app.FIBISUIFigure, 'push');
            app.ClearGroupButton.ButtonPushedFcn = createCallbackFcn(app, @ClearGroupButtonPushed, true);
            app.ClearGroupButton.Text = 'Clear Group';
            % Show the figure after all components are created
            app.FIBISUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IBIS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FIBISUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FIBISUIFigure)
        end
    end
end